classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PleaseselectyourunittypeconversiontypefromthedropdownmenusLabel  matlab.ui.control.Label
        UnitTypeDropDownLabel        matlab.ui.control.Label
        UnitTypeDropDown             matlab.ui.control.DropDown
        ConversionTypeDropDownLabel  matlab.ui.control.Label
        ConversionTypeDropDown       matlab.ui.control.DropDown
        PleaseenterthedatayouwantconvertedinthedatafieldbelowLabel  matlab.ui.control.Label
        EnterdataEditFieldLabel      matlab.ui.control.Label
        EnterdataEditField           matlab.ui.control.NumericEditField
        ConvertButton                matlab.ui.control.Button
        ResultLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UnitTypeDropDown
        function UnitTypeDropDownValueChanged(app, event)
            value = app.UnitTypeDropDown.Value;
            if strcmp(value, 'Temperature')
                app.ConversionTypeDropDown.Items = {'Select', 'Celcius to Farenheit', 'Farenheit to Celcius'};
            elseif strcmp(value, 'Length')
                app.ConversionTypeDropDown.Items = {'Select', 'Centimetres to Inches', 'Inches to Centimetres', 'Metres to Feet', 'Feet to Metres'};
            elseif strcmp(value, 'Distance')
                app.ConversionTypeDropDown.Items = {'Select', 'Kilometres to Miles', 'Miles to Kilometres'};
            elseif strcmp(value, 'Mass')
                app.ConversionTypeDropDown.Items = {'Select', 'Grams to Ounces', 'Ounces to Grams', 'Kilograms to Pounds', 'Pounds to Kilograms', 'Metric Tonnes to Imperial Tons', 'Imperial Tons to Metric Tonnes'};
            end
        end

        % Value changed function: ConversionTypeDropDown
        function ConversionTypeDropDownValueChanged(app, event)
            value = app.ConversionTypeDropDown.Value;
            
        end

        % Value changed function: EnterdataEditField
        function EnterdataEditFieldValueChanged(app, event)
            value = app.EnterdataEditField.Value;
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            data = app.EnterdataEditField.Value;
            unitype = app.ConversionTypeDropDown.Value;
            m = strcmp(unitype, 'Celcius to Farenheit') | strcmp(unitype, 'Farenheit to Celcius');           
            if (data<0) & (m==0)
                 app.ResultLabel.Text = ['The data you have entered is negative. Please enter a positive number to continue.'];
            elseif (data==0) & (m==0)
                app.ResultLabel.Text = ['You have not entered any data. Please enter valid data to continue.'];
            else
                [x, y] = convfun(data, unitype);
                app.ResultLabel.Text = ['The converted data is ' num2str(x) y];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseselectyourunittypeconversiontypefromthedropdownmenusLabel = uilabel(app.UIFigure);
            app.PleaseselectyourunittypeconversiontypefromthedropdownmenusLabel.Text = 'Please select your unit type & conversion type from the drop down menus';
            app.UnitTypeDropDownLabel = uilabel(app.UIFigure);
            app.UnitTypeDropDownLabel.Text = 'Unit Type';
            app.UnitTypeDropDown = uidropdown(app.UIFigure);
            app.UnitTypeDropDown.Items = {'Temperature', 'Length', 'Distance', 'Mass'};
            app.UnitTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitTypeDropDownValueChanged, true);
            app.UnitTypeDropDown.Value = 'Temperature';
            app.ConversionTypeDropDownLabel = uilabel(app.UIFigure);
            app.ConversionTypeDropDownLabel.Text = 'Conversion Type';
            app.ConversionTypeDropDown = uidropdown(app.UIFigure);
            app.ConversionTypeDropDown.Items = {'Select', 'Celcius to Farenheit', 'Farenheit to Celcius', 'Centimetres to Inches', 'Inches to Centimetres', 'Metres to Feet', 'Feet to Metres', 'Kilometres to Miles', 'Miles to Kilometres', 'Grams to Ounces', 'Ounces to Grams', 'Kilograms to Pounds', 'Pounds to Kilograms', 'Metric Tonnes to Imperial Tons', 'Imperial Tons to Metric Tonnes'};
            app.ConversionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConversionTypeDropDownValueChanged, true);
            app.ConversionTypeDropDown.Value = 'Select';
            app.PleaseenterthedatayouwantconvertedinthedatafieldbelowLabel = uilabel(app.UIFigure);
            app.PleaseenterthedatayouwantconvertedinthedatafieldbelowLabel.Text = 'Please enter the data you want converted in the data field below:';
            app.EnterdataEditFieldLabel = uilabel(app.UIFigure);
            app.EnterdataEditFieldLabel.Text = 'Enter data';
            app.EnterdataEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterdataEditField.ValueChangedFcn = createCallbackFcn(app, @EnterdataEditFieldValueChanged, true);
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end